classdef Glide_performance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WingSpaninmLabel                matlab.ui.control.Label
        WingSpaninmEditField            matlab.ui.control.NumericEditField
        GlideperformanceCalculatorLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DesignedbyGouthamGaneshMHLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        WingAreainm2Label               matlab.ui.control.Label
        WingAreainm2EditField           matlab.ui.control.NumericEditField
        ParasiteDragCoefficientCD0Label  matlab.ui.control.Label
        ParasiteDragCoefficientCD0EditField  matlab.ui.control.NumericEditField
        OswaldEfficiencyFactoreLabel    matlab.ui.control.Label
        OswaldEfficiencyFactoreEditField  matlab.ui.control.NumericEditField
        AircraftAltitudehinkmEditFieldLabel  matlab.ui.control.Label
        AircraftAltitudehinkmEditField  matlab.ui.control.NumericEditField
        AspectRatioEditFieldLabel       matlab.ui.control.Label
        AspectRatioEditField            matlab.ui.control.NumericEditField
        MassoftheAircraftinkgLabel      matlab.ui.control.Label
        MassoftheAircraftinkgEditField  matlab.ui.control.NumericEditField
        LDmaxLabel                      matlab.ui.control.Label
        LDmaxEditField                  matlab.ui.control.NumericEditField
        MinimumGlideAngleminindegreesEditFieldLabel  matlab.ui.control.Label
        MinimumGlideAngleminindegreesEditField  matlab.ui.control.NumericEditField
        MaximumRangeRmaxinkminapoweroffglideatanaltitudehEditFieldLabel  matlab.ui.control.Label
        MaximumRangeRmaxinkminapoweroffglideatanaltitudehEditField  matlab.ui.control.NumericEditField
        CoefficientofLiftCLatLDmaxLabel  matlab.ui.control.Label
        CoefficientofLiftCLatLDmaxEditField  matlab.ui.control.NumericEditField
        EquilibriumFlightVelocityinmsataltitudehLabel  matlab.ui.control.Label
        EquilibriumFlightVelocityinmsataltitudehEditField  matlab.ui.control.NumericEditField
        SinkRateinmsLabel               matlab.ui.control.Label
        SinkRateinmsEditField           matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = glide(app,b,s,m,cd0,e,h)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            b=app.WingSpaninmEditField.Value;
            s=app.WingAreainm2EditField.Value;
            m=app.MassoftheAircraftinkgEditField.Value;
            cd0=app.ParasiteDragCoefficientCD0EditField.Value;
            e=app.OswaldEfficiencyFactoreEditField.Value;
            h=app.AircraftAltitudehinkmEditField.Value;
            
            ar=(b^2)/s;
            ldmax=(sqrt(cd0*pi*e*ar))/(2*cd0)
            theta=atand(1/(ldmax))
            rmax=h*(ldmax)
            w=m*9.8;
            k=(1)/(pi*e*ar);           
            if h<11
                tratio=(1-(h/44.331));
                d=1.225*(tratio)^4.256;
                end
            if h==11
                 d=0.36392
            end
            if h>11
                d=0.036392*exp((-9.80665*([h*1000]-11000))/(287.05287*216.65));
                end
            
          syms v
          ldmaxx=(sqrt(cd0*pi*e*ar))/(2*cd0)
          l=0;
          tol=0.05;
          for v=0:1:450;
              cll=(w)/(0.5*d*v^2*s);
              cdd=cd0+(k*cll^2);
              x=(cll/cdd);
              if abs(x-ldmaxx)<tol;
                  l=cll;     
              end
          end
            thetav=theta*(pi/180);
          vv=sqrt((2.*cos(thetav).*w)/(d.*l.*s));
          sr=(vv)*sin(thetav)
          
          %% Plotting 
            for h=1:0.05:10;
                tratio=(1-(h/44.331));
                d=1.225*(tratio)^4.256;
                glidevelocity=sqrt((2*cosd(3.395)*2984.8)/(d*0.634))
                plot(app.UIAxes,[h],[glidevelocity],'.')
                hold(app.UIAxes,'on')
                           
          end
          hold(app.UIAxes,'off')
          app.AspectRatioEditField.Value=ar;
            app.LDmaxEditField.Value= ldmax;
            app.MinimumGlideAngleminindegreesEditField.Value=theta;
            app.MaximumRangeRmaxinkminapoweroffglideatanaltitudehEditField.Value=rmax;
            app.CoefficientofLiftCLatLDmaxEditField.Value=l;
            app.EquilibriumFlightVelocityinmsataltitudehEditField.Value=vv;
            app.SinkRateinmsEditField.Value=-1*sr;
            
        glide(app,b,s,m,cd0,e,h)    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WingSpaninmLabel = uilabel(app.UIFigure);
            app.WingSpaninmLabel.Text = 'Wing Span in m ';
            app.WingSpaninmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GlideperformanceCalculatorLabel = uilabel(app.UIFigure);
            app.GlideperformanceCalculatorLabel.Text = 'Glide performance Calculator';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'msruas logo.jpg';
            app.DesignedbyGouthamGaneshMHLabel = uilabel(app.UIFigure);
            app.DesignedbyGouthamGaneshMHLabel.Text = 'Designed by Goutham Ganesh M H';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.WingAreainm2Label = uilabel(app.UIFigure);
            app.WingAreainm2Label.Text = 'Wing Area in m^2 ';
            app.WingAreainm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParasiteDragCoefficientCD0Label = uilabel(app.UIFigure);
            app.ParasiteDragCoefficientCD0Label.Text = 'Parasite Drag Coefficient CD0 ';
            app.ParasiteDragCoefficientCD0EditField = uieditfield(app.UIFigure, 'numeric');
            app.OswaldEfficiencyFactoreLabel = uilabel(app.UIFigure);
            app.OswaldEfficiencyFactoreLabel.Text = 'Oswald Efficiency Factor e ';
            app.OswaldEfficiencyFactoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.AircraftAltitudehinkmEditFieldLabel = uilabel(app.UIFigure);
            app.AircraftAltitudehinkmEditFieldLabel.Text = 'Aircraft Altitude h in km ';
            app.AircraftAltitudehinkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AspectRatioEditFieldLabel = uilabel(app.UIFigure);
            app.AspectRatioEditFieldLabel.Text = 'Aspect Ratio ';
            app.AspectRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassoftheAircraftinkgLabel = uilabel(app.UIFigure);
            app.MassoftheAircraftinkgLabel.Text = 'Mass of the Aircraft in kg ';
            app.MassoftheAircraftinkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.LDmaxLabel = uilabel(app.UIFigure);
            app.LDmaxLabel.Text = '( L / D ) max ';
            app.LDmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumGlideAngleminindegreesEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumGlideAngleminindegreesEditFieldLabel.Text = 'Minimum Glide Angle θ min in degrees';
            app.MinimumGlideAngleminindegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumRangeRmaxinkminapoweroffglideatanaltitudehEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumRangeRmaxinkminapoweroffglideatanaltitudehEditFieldLabel.Text = {'Maximum Range, R max in km '; 'in a power off glide at an altitude h'};
            app.MaximumRangeRmaxinkminapoweroffglideatanaltitudehEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoefficientofLiftCLatLDmaxLabel = uilabel(app.UIFigure);
            app.CoefficientofLiftCLatLDmaxLabel.Text = {'Coefficient of Lift'; 'CL at ( L / D ) max'};
            app.CoefficientofLiftCLatLDmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.EquilibriumFlightVelocityinmsataltitudehLabel = uilabel(app.UIFigure);
            app.EquilibriumFlightVelocityinmsataltitudehLabel.Text = {'Equilibrium Flight'; 'Velocity in m/s at altitude h '};
            app.EquilibriumFlightVelocityinmsataltitudehEditField = uieditfield(app.UIFigure, 'numeric');
            app.SinkRateinmsLabel = uilabel(app.UIFigure);
            app.SinkRateinmsLabel.Text = 'Sink Rate in m/s ';
            app.SinkRateinmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Variation of Equilibrium Glide Velocity With Altitude')
            xlabel(app.UIAxes, 'Altitude in km')
            ylabel(app.UIAxes, 'Equilibrium Glide Velocity in m/s')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Glide_performance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end