classdef Landing_performance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EmptymassoftheAircraftinkgEditFieldLabel  matlab.ui.control.Label
        EmptymassoftheAircraftinkgEditField  matlab.ui.control.NumericEditField
        Wingareainm2Label               matlab.ui.control.Label
        Wingareainm2EditField           matlab.ui.control.NumericEditField
        ParasiteDragCoefficinetCD0Label  matlab.ui.control.Label
        ParasiteDragCoefficinetCD0EditField  matlab.ui.control.NumericEditField
        CoefficientofRollingFrictionLabel  matlab.ui.control.Label
        CoefficientofRollingFrictionEditField  matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        CLmaxduringgroundrollLabel      matlab.ui.control.Label
        CLmaxduringgroundrollEditField  matlab.ui.control.NumericEditField
        AltitudeoftheAircraftinkmLabel  matlab.ui.control.Label
        AltitudeoftheAircraftinkmEditField  matlab.ui.control.NumericEditField
        LandinggroundrolldistanceinmEditFieldLabel  matlab.ui.control.Label
        LandinggroundrolldistanceinmEditField  matlab.ui.control.NumericEditField
        LandingPerformanceCalculatorLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DesignedbyGouthamGaneshMHLabel  matlab.ui.control.Label
        TouchdownvelocityinmsEditFieldLabel  matlab.ui.control.Label
        TouchdownvelocityinmsEditField  matlab.ui.control.NumericEditField
        AverageTouchdownvelocityinmsEditFieldLabel  matlab.ui.control.Label
        AverageTouchdownvelocityinmsEditField  matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = takeoff(app,m,s,cd0,u,clmax,h)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            m=app.EmptymassoftheAircraftinkgEditField.Value;
            s=app.Wingareainm2EditField.Value;
            cd0=app.ParasiteDragCoefficinetCD0EditField.Value;
            u=app.CoefficientofRollingFrictionEditField.Value;
            clmax=app.CLmaxduringgroundrollEditField.Value;
            h=app.AltitudeoftheAircraftinkmEditField.Value;
            
            if h<11
                tratio=(1-(h/44.331));
                d=1.225*(tratio)^4.256;    
            end
            if h==11
                 d=0.36392
            end
            if h>11
                d=0.036392*exp((-9.80665*([h*1000]-11000))/(287.05287*216.65));
            end
             w=m*9.8;
            vlo=1.3*(sqrt((2*w)/(d*s*clmax)));
            vvlo=0.7*vlo;
            drag=0.5*d*vvlo^2 *s*cd0;
            landing_ground_roll_distance= (1.69*w^2)/(9.8*d*s*clmax*(drag+(u*w)));
            
            app.LandinggroundrolldistanceinmEditField.Value=landing_ground_roll_distance;
            app.TouchdownvelocityinmsEditField.Value=vlo;
            app.AverageTouchdownvelocityinmsEditField.Value=vvlo;
              takeoff(app,m,s,cd0,u,clmax,h)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EmptymassoftheAircraftinkgEditFieldLabel = uilabel(app.UIFigure);
            app.EmptymassoftheAircraftinkgEditFieldLabel.Text = 'Empty mass of the Aircraft in kg ';
            app.EmptymassoftheAircraftinkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.Wingareainm2Label = uilabel(app.UIFigure);
            app.Wingareainm2Label.Text = 'Wing area in m^2 ';
            app.Wingareainm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParasiteDragCoefficinetCD0Label = uilabel(app.UIFigure);
            app.ParasiteDragCoefficinetCD0Label.Text = 'Parasite Drag Coefficinet CD0 ';
            app.ParasiteDragCoefficinetCD0EditField = uieditfield(app.UIFigure, 'numeric');
            app.CoefficientofRollingFrictionLabel = uilabel(app.UIFigure);
            app.CoefficientofRollingFrictionLabel.Text = 'Coefficient of Rolling Friction ';
            app.CoefficientofRollingFrictionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CLmaxduringgroundrollLabel = uilabel(app.UIFigure);
            app.CLmaxduringgroundrollLabel.Text = 'CL max during ground roll ';
            app.CLmaxduringgroundrollEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudeoftheAircraftinkmLabel = uilabel(app.UIFigure);
            app.AltitudeoftheAircraftinkmLabel.Text = 'Altitude of the Aircraft in km ';
            app.AltitudeoftheAircraftinkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LandinggroundrolldistanceinmEditFieldLabel = uilabel(app.UIFigure);
            app.LandinggroundrolldistanceinmEditFieldLabel.Text = 'Landing ground roll distance in m ';
            app.LandinggroundrolldistanceinmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LandingPerformanceCalculatorLabel = uilabel(app.UIFigure);
            app.LandingPerformanceCalculatorLabel.Text = 'Landing Performance Calculator ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'msruas logo.jpg';
            app.DesignedbyGouthamGaneshMHLabel = uilabel(app.UIFigure);
            app.DesignedbyGouthamGaneshMHLabel.Text = 'Designed by Goutham Ganesh M H';
            app.TouchdownvelocityinmsEditFieldLabel = uilabel(app.UIFigure);
            app.TouchdownvelocityinmsEditFieldLabel.Text = 'Touchdown velocity in m/s ';
            app.TouchdownvelocityinmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageTouchdownvelocityinmsEditFieldLabel = uilabel(app.UIFigure);
            app.AverageTouchdownvelocityinmsEditFieldLabel.Text = 'Average Touchdown velocity in m/s ';
            app.AverageTouchdownvelocityinmsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Landing_performance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end