classdef Takeoff_performance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MassoftheAircraftinkgLabel      matlab.ui.control.Label
        MassoftheAircraftinkgEditField  matlab.ui.control.NumericEditField
        WingspaninmLabel                matlab.ui.control.Label
        WingspaninmEditField            matlab.ui.control.NumericEditField
        Wingareainm2Label               matlab.ui.control.Label
        Wingareainm2EditField           matlab.ui.control.NumericEditField
        ParasiteDragCoefficinetCD0Label  matlab.ui.control.Label
        ParasiteDragCoefficinetCD0EditField  matlab.ui.control.NumericEditField
        OswaldEfficiencyFactoreLabel    matlab.ui.control.Label
        OswaldEfficiencyFactoreEditField  matlab.ui.control.NumericEditField
        CoefficientofRollingFrictionLabel  matlab.ui.control.Label
        CoefficientofRollingFrictionEditField  matlab.ui.control.NumericEditField
        HeightofWingsabovethegroundinmLabel  matlab.ui.control.Label
        HeightofWingsabovethegroundinmEditField  matlab.ui.control.NumericEditField
        ThrustavailableatsealevelinNLabel  matlab.ui.control.Label
        ThrustavailableatsealevelinNEditField  matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        CLmaxduringgroundrollLabel      matlab.ui.control.Label
        CLmaxduringgroundrollEditField  matlab.ui.control.NumericEditField
        AltitudeoftheAircraftinkmLabel  matlab.ui.control.Label
        AltitudeoftheAircraftinkmEditField  matlab.ui.control.NumericEditField
        GroundEffectFactorLabel         matlab.ui.control.Label
        GroundEffectFactorEditField     matlab.ui.control.NumericEditField
        LiftoffVelocityinmsLabel        matlab.ui.control.Label
        LiftoffVelocityinmsEditField    matlab.ui.control.NumericEditField
        LiftoffDistanceinmLabel         matlab.ui.control.Label
        LiftoffDistanceinmEditField     matlab.ui.control.NumericEditField
        TakeoffPerformanceCalculatorLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DesignedbyGouthamGaneshMHLabel  matlab.ui.control.Label
        AverageLiftoffvelocityinmsLabel  matlab.ui.control.Label
        AverageLiftoffvelocityinmsEditField  matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = takeoff(app,m,b,s,cd0,e,u,hw,tsl,clmax,h)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            m=app.MassoftheAircraftinkgEditField.Value;
            b=app.WingspaninmEditField.Value;
            s=app.Wingareainm2EditField.Value;
            cd0=app.ParasiteDragCoefficinetCD0EditField.Value;
            e=app.OswaldEfficiencyFactoreEditField.Value;
            u=app.CoefficientofRollingFrictionEditField.Value;
            hw=app.HeightofWingsabovethegroundinmEditField.Value;
            tsl=app.ThrustavailableatsealevelinNEditField.Value;
            clmax=app.CLmaxduringgroundrollEditField.Value;
            h=app.AltitudeoftheAircraftinkmEditField.Value;
            
            if h<11
                tratio=(1-(h/44.331));
                d=1.225*(tratio)^4.256;
                
            end
            if h==11
                 d=0.36392
            end
            if h>11
                d=0.036392*exp((-9.80665*([h*1000]-11000))/(287.05287*216.65));
                
            end
            
            ar=(b^2)/s;
            w=m*9.8;
            k=(1)/(pi*e*ar);
            gfe=(16*hw/b)^2/(1+(16*hw/b)^2);
            vlo=1.2*(sqrt((2*w)/(d*s*clmax)));
            vvlo=0.7*vlo;
            lift= 0.5*d*vvlo^2 * s* clmax;
            drag=0.5*d*vvlo^2 * (cd0 + gfe*((clmax^2)/pi*e*ar));
            liftoffdistance= (1.44*w^2)/(9.8*d*s*clmax*(tsl-(drag+u*(w-lift))));
            
              app.GroundEffectFactorEditField.Value=gfe;
            app.LiftoffVelocityinmsEditField.Value=vlo;
            app.LiftoffDistanceinmEditField.Value=liftoffdistance;
            app.AverageLiftoffvelocityinmsEditField.Value=vvlo;
              takeoff(app,m,b,s,cd0,e,u,hw,tsl,clmax,h)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MassoftheAircraftinkgLabel = uilabel(app.UIFigure);
            app.MassoftheAircraftinkgLabel.Text = 'Mass of the Aircraft in kg ';
            app.MassoftheAircraftinkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.WingspaninmLabel = uilabel(app.UIFigure);
            app.WingspaninmLabel.Text = 'Wing span in m ';
            app.WingspaninmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Wingareainm2Label = uilabel(app.UIFigure);
            app.Wingareainm2Label.Text = 'Wing area in m^2 ';
            app.Wingareainm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParasiteDragCoefficinetCD0Label = uilabel(app.UIFigure);
            app.ParasiteDragCoefficinetCD0Label.Text = 'Parasite Drag Coefficinet CD0 ';
            app.ParasiteDragCoefficinetCD0EditField = uieditfield(app.UIFigure, 'numeric');
            app.OswaldEfficiencyFactoreLabel = uilabel(app.UIFigure);
            app.OswaldEfficiencyFactoreLabel.Text = 'Oswald Efficiency Factor e ';
            app.OswaldEfficiencyFactoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoefficientofRollingFrictionLabel = uilabel(app.UIFigure);
            app.CoefficientofRollingFrictionLabel.Text = 'Coefficient of Rolling Friction ';
            app.CoefficientofRollingFrictionEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightofWingsabovethegroundinmLabel = uilabel(app.UIFigure);
            app.HeightofWingsabovethegroundinmLabel.Text = 'Height of Wings above the ground in m ';
            app.HeightofWingsabovethegroundinmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThrustavailableatsealevelinNLabel = uilabel(app.UIFigure);
            app.ThrustavailableatsealevelinNLabel.Text = 'Thrust available at sea level in N ';
            app.ThrustavailableatsealevelinNEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CLmaxduringgroundrollLabel = uilabel(app.UIFigure);
            app.CLmaxduringgroundrollLabel.Text = 'CL max during ground roll ';
            app.CLmaxduringgroundrollEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudeoftheAircraftinkmLabel = uilabel(app.UIFigure);
            app.AltitudeoftheAircraftinkmLabel.Text = 'Altitude of the Aircraft in km ';
            app.AltitudeoftheAircraftinkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.GroundEffectFactorLabel = uilabel(app.UIFigure);
            app.GroundEffectFactorLabel.Text = 'Ground Effect Factor ';
            app.GroundEffectFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiftoffVelocityinmsLabel = uilabel(app.UIFigure);
            app.LiftoffVelocityinmsLabel.Text = 'Lift off Velocity in m/s ';
            app.LiftoffVelocityinmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LiftoffDistanceinmLabel = uilabel(app.UIFigure);
            app.LiftoffDistanceinmLabel.Text = 'Lift off Distance in m ';
            app.LiftoffDistanceinmEditField = uieditfield(app.UIFigure, 'numeric');
            app.TakeoffPerformanceCalculatorLabel = uilabel(app.UIFigure);
            app.TakeoffPerformanceCalculatorLabel.Text = 'Takeoff Performance Calculator ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'msruas logo.jpg';
            app.DesignedbyGouthamGaneshMHLabel = uilabel(app.UIFigure);
            app.DesignedbyGouthamGaneshMHLabel.Text = 'Designed by Goutham Ganesh M H';
            app.AverageLiftoffvelocityinmsLabel = uilabel(app.UIFigure);
            app.AverageLiftoffvelocityinmsLabel.Text = 'Average Lift off  velocity in m/s ';
            app.AverageLiftoffvelocityinmsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Takeoff_performance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end