classdef rate_of_climb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AltitudehinkmLabel              matlab.ui.control.Label
        AltitudehinkmEditField          matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        FlightvelocityinmsLabel         matlab.ui.control.Label
        FlightvelocityinmsEditField     matlab.ui.control.NumericEditField
        RateofClimbPerformanceCalculatorLabel  matlab.ui.control.Label
        MassoftheAircraftinkgLabel      matlab.ui.control.Label
        MassoftheAircraftinkgEditField  matlab.ui.control.NumericEditField
        WingAreainm2Label               matlab.ui.control.Label
        WingAreainm2EditField           matlab.ui.control.NumericEditField
        WeightofTheAircraftinkgLabel    matlab.ui.control.Label
        WeightofTheAircraftinkgEditField  matlab.ui.control.NumericEditField
        Wingloadinginkgm2Label          matlab.ui.control.Label
        Wingloadinginkgm2EditField      matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        DynamicPressureinPaLabel        matlab.ui.control.Label
        DynamicPressureinPaEditField    matlab.ui.control.NumericEditField
        AspectRatioEditFieldLabel       matlab.ui.control.Label
        AspectRatioEditField            matlab.ui.control.NumericEditField
        OswaldEfficiencyFactoreLabel    matlab.ui.control.Label
        OswaldEfficiencyFactoreEditField  matlab.ui.control.NumericEditField
        KLabel                          matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        Densityinkgm3ataltitudehEditFieldLabel  matlab.ui.control.Label
        Densityinkgm3ataltitudehEditField  matlab.ui.control.NumericEditField
        ParasiteDragCoefficientCd0EditFieldLabel  matlab.ui.control.Label
        ParasiteDragCoefficientCd0EditField  matlab.ui.control.NumericEditField
        ThrustavailabeatSeaLevelinNEditFieldLabel  matlab.ui.control.Label
        ThrustavailabeatSeaLevelinNEditField  matlab.ui.control.NumericEditField
        PowerAvaiableinWEditFieldLabel  matlab.ui.control.Label
        PowerAvaiableinWEditField       matlab.ui.control.NumericEditField
        PowerRequiredinWEditFieldLabel  matlab.ui.control.Label
        PowerRequiredinWEditField       matlab.ui.control.NumericEditField
        RateOfClimbinmsataltitudehEditFieldLabel  matlab.ui.control.Label
        RateOfClimbinmsataltitudehEditField  matlab.ui.control.NumericEditField
        MaximumRateOfClimbinmsataltitudehLabel  matlab.ui.control.Label
        MaximumRateOfClimbinmsataltitudehEditField  matlab.ui.control.NumericEditField
        CoefficientOfLiftLabel          matlab.ui.control.Label
        CoefficientOfLiftEditField      matlab.ui.control.NumericEditField
        VelocityforMaximumRateOfClimbinmsataltitudehLabel  matlab.ui.control.Label
        VelocityforMaximumRateOfClimbinmsataltitudehEditField  matlab.ui.control.NumericEditField
        DesignedbyGouthamGaneshMHLabel  matlab.ui.control.Label
        ThrusttoWeightratioatsealevelinNkgEditFieldLabel  matlab.ui.control.Label
        ThrusttoWeightratioatsealevelinNkgEditField  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

        methods (Access = private)
        
        function rc(app,h,v,m,s,ar,e,cd0,tsl)
             
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: AltitudehinkmEditField, CalculateButton, 
        % FlightvelocityinmsEditField, KEditField, 
        % MassoftheAircraftinkgEditField, 
        % MaximumRateOfClimbinmsataltitudehEditField, 
        % RateOfClimbinmsataltitudehEditField, UIAxes, UIAxes2, 
        % VelocityforMaximumRateOfClimbinmsataltitudehEditField, 
        % WeightofTheAircraftinkgEditField, WingAreainm2EditField
        function CalculateButtonPushed(app, event)
            h=app.AltitudehinkmEditField.Value;
            v=app.FlightvelocityinmsEditField.Value;
            m=app.MassoftheAircraftinkgEditField.Value;
            s=app.WingAreainm2EditField.Value;
            ar=app.AspectRatioEditField.Value;
            e=app.OswaldEfficiencyFactoreEditField.Value;
            cd0=app.ParasiteDragCoefficientCd0EditField.Value;
            tsl=app.ThrustavailabeatSeaLevelinNEditField.Value;
            
             
            if h<11
                tratio=(1-(h/44.331));
                d=1.225*(tratio)^4.256;
                
            end
            if h==11
                 d=0.36392
            end
            if h>11
                d=0.036392*exp((-9.80665*([h*1000]-11000))/(287.05287*216.65));
                
            end
                         
                q=0.5*d*v^2;  % Dynamic Pressure
                k=1/(3.14*ar*e)
                w=9.81*m;    % Weight of the Aircraft
                wl=w/s;    % Wing loading
                tw=tsl/w;    % Thrust to Weight ratio
                qs=q*s;
                cd0qs=cd0*qs;
                cl=w/qs;  % Co-efficient of Lift
                cdi=qs*k*cl^2;
                pr=(cd0qs+cdi)*v;  % Power required
                pa=tsl*v;  %Power Available
                roc=(pa-pr)/w;  %Rate of Climb
                
                
                %Plotting 
                
                syms f
               rocpm=-inf;
               frocpm=0;
                 
                for f=50:10:310;
                    qsp=s*0.5*d*f^2;
                    cd0qsp=cd0*qsp;
                    clp=w/(qsp);
                    cdip=qsp*k*clp^2;
                    prp=(cd0qsp+cdip)*f;
                    pap=tsl*f;
                    rocp=(pap-prp)/w;
                    plot(app.UIAxes,[f],[rocp],'*')
                    hold(app.UIAxes,'on')
                    if rocp>rocpm;
                        rocpm=rocp;
                        frocpm=f;
                    end                       
                end
               
                hold(app.UIAxes,'off')
                 
                
   syms hpp
for hpp=0:.1:10.5;
    tratiopp=(1-(hpp/44.331));
    dpp=1.225*(tratiopp)^4.256;
    qpp=0.5*dpp*v^2;
    qspp=qpp*s;
    cd0qspp=cd0*qspp;
    clpp=w/qspp;
    cdipp=qspp*k*clpp^2;
    prpp=(cd0qspp+cdipp)*v;
    papp=tsl*v;
    rocpp=(papp-prpp)/w;
    plot(app.UIAxes2,[hpp],[rocpp],'.')
    hold(app.UIAxes2,'on')
      
end
hold(app.UIAxes2,'off')
                
                app.Densityinkgm3ataltitudehEditField.Value=d;
             app.WeightofTheAircraftinkgEditField.Value=w;
            app.Wingloadinginkgm2EditField.Value=wl;
           app.DynamicPressureinPaEditField.Value=q;
             app.KEditField.Value=k;
             app.CoefficientOfLiftEditField.Value=cl;           
             app.PowerRequiredinWEditField.Value=pr;
             app.PowerAvaiableinWEditField.Value=pa;
             app.RateOfClimbinmsataltitudehEditField.Value=roc;
            app.MaximumRateOfClimbinmsataltitudehEditField.Value=rocpm;
            app.VelocityforMaximumRateOfClimbinmsataltitudehEditField.Value=frocpm;
            app.ThrusttoWeightratioatsealevelinNkgEditField.Value=tw;
            rc(app,h,v,m,s,ar,e,cd0,tsl)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AltitudehinkmLabel = uilabel(app.UIFigure);
            app.AltitudehinkmLabel.Text = 'Altitude h in km  ';
            app.AltitudehinkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudehinkmEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.FlightvelocityinmsLabel = uilabel(app.UIFigure);
            app.FlightvelocityinmsLabel.Text = 'Flight velocity in m/s  ';
            app.FlightvelocityinmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlightvelocityinmsEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.RateofClimbPerformanceCalculatorLabel = uilabel(app.UIFigure);
            app.RateofClimbPerformanceCalculatorLabel.Text = 'Rate of Climb Performance Calculator';
            app.MassoftheAircraftinkgLabel = uilabel(app.UIFigure);
            app.MassoftheAircraftinkgLabel.Text = 'Mass of the Aircraft in kg  ';
            app.MassoftheAircraftinkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassoftheAircraftinkgEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.WingAreainm2Label = uilabel(app.UIFigure);
            app.WingAreainm2Label.Text = 'Wing Area in m^2  ';
            app.WingAreainm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.WingAreainm2EditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.WeightofTheAircraftinkgLabel = uilabel(app.UIFigure);
            app.WeightofTheAircraftinkgLabel.Text = 'Weight of The Aircraft in kg  ';
            app.WeightofTheAircraftinkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightofTheAircraftinkgEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Wingloadinginkgm2Label = uilabel(app.UIFigure);
            app.Wingloadinginkgm2Label.Text = 'Wing loading in kg/m^2  ';
            app.Wingloadinginkgm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'msruas logo.jpg';
            app.DynamicPressureinPaLabel = uilabel(app.UIFigure);
            app.DynamicPressureinPaLabel.Text = 'Dynamic Pressure in Pa  ';
            app.DynamicPressureinPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AspectRatioEditFieldLabel = uilabel(app.UIFigure);
            app.AspectRatioEditFieldLabel.Text = 'Aspect Ratio';
            app.AspectRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.AspectRatioEditField.ValueDisplayFormat = '%.5f';
            app.OswaldEfficiencyFactoreLabel = uilabel(app.UIFigure);
            app.OswaldEfficiencyFactoreLabel.Text = '''Oswald Efficiency Factor e ''';
            app.OswaldEfficiencyFactoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.OswaldEfficiencyFactoreEditField.ValueDisplayFormat = '%.4f';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K ';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Densityinkgm3ataltitudehEditFieldLabel = uilabel(app.UIFigure);
            app.Densityinkgm3ataltitudehEditFieldLabel.Text = 'Density in kg/m^3 at altitude h';
            app.Densityinkgm3ataltitudehEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParasiteDragCoefficientCd0EditFieldLabel = uilabel(app.UIFigure);
            app.ParasiteDragCoefficientCd0EditFieldLabel.Text = 'Parasite Drag Coefficient Cd0';
            app.ParasiteDragCoefficientCd0EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParasiteDragCoefficientCd0EditField.ValueDisplayFormat = '%.4f';
            app.ThrustavailabeatSeaLevelinNEditFieldLabel = uilabel(app.UIFigure);
            app.ThrustavailabeatSeaLevelinNEditFieldLabel.Text = 'Thrust availabe at Sea Level in N ';
            app.ThrustavailabeatSeaLevelinNEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerAvaiableinWEditFieldLabel = uilabel(app.UIFigure);
            app.PowerAvaiableinWEditFieldLabel.Text = 'Power Avaiable in W ';
            app.PowerAvaiableinWEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerRequiredinWEditFieldLabel = uilabel(app.UIFigure);
            app.PowerRequiredinWEditFieldLabel.Text = 'Power Required in W ';
            app.PowerRequiredinWEditField = uieditfield(app.UIFigure, 'numeric');
            app.RateOfClimbinmsataltitudehEditFieldLabel = uilabel(app.UIFigure);
            app.RateOfClimbinmsataltitudehEditFieldLabel.Text = 'Rate Of Climb in m/s at altitude h';
            app.RateOfClimbinmsataltitudehEditField = uieditfield(app.UIFigure, 'numeric');
            app.RateOfClimbinmsataltitudehEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.MaximumRateOfClimbinmsataltitudehLabel = uilabel(app.UIFigure);
            app.MaximumRateOfClimbinmsataltitudehLabel.Text = 'Maximum Rate Of Climb in m/s at altitude h ';
            app.MaximumRateOfClimbinmsataltitudehEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumRateOfClimbinmsataltitudehEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CoefficientOfLiftLabel = uilabel(app.UIFigure);
            app.CoefficientOfLiftLabel.Text = 'Coefficient Of Lift  ';
            app.CoefficientOfLiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityforMaximumRateOfClimbinmsataltitudehLabel = uilabel(app.UIFigure);
            app.VelocityforMaximumRateOfClimbinmsataltitudehLabel.Text = 'Velocity for Maximum Rate Of Climb in m/s, at altitude h ';
            app.VelocityforMaximumRateOfClimbinmsataltitudehEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityforMaximumRateOfClimbinmsataltitudehEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.DesignedbyGouthamGaneshMHLabel = uilabel(app.UIFigure);
            app.DesignedbyGouthamGaneshMHLabel.Text = 'Designed by Goutham Ganesh M H ';
            app.ThrusttoWeightratioatsealevelinNkgEditFieldLabel = uilabel(app.UIFigure);
            app.ThrusttoWeightratioatsealevelinNkgEditFieldLabel.Text = 'Thrust to Weight ratio at sea level in N/kg ';
            app.ThrusttoWeightratioatsealevelinNkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Rate Of Climb Vs. Velocity plot at altitude h')
            xlabel(app.UIAxes, 'Velocity in m/s ')
            ylabel(app.UIAxes, 'Rate Of Climb in m/s')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Rate of Climb Vs. Altitude plot at velocity v')
            xlabel(app.UIAxes2, 'Altiutde h in km')
            ylabel(app.UIAxes2, 'Rate of Climb in m/s')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rate_of_climb

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end