classdef turning_performance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MassoftheAircraftinkgEditFieldLabel  matlab.ui.control.Label
        MassoftheAircraftinkgEditField  matlab.ui.control.NumericEditField
        Wingareainm2Label               matlab.ui.control.Label
        Wingareainm2EditField           matlab.ui.control.NumericEditField
        MaximumloadfactorEditFieldLabel  matlab.ui.control.Label
        MaximumloadfactorEditField      matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        CLmaxEditFieldLabel             matlab.ui.control.Label
        CLmaxEditField                  matlab.ui.control.NumericEditField
        AltitudeoftheAircraftinkmLabel  matlab.ui.control.Label
        AltitudeoftheAircraftinkmEditField  matlab.ui.control.NumericEditField
        MaximumTurnrateindegsLabel      matlab.ui.control.Label
        MaximumTurnrateindegsEditField  matlab.ui.control.NumericEditField
        TurnPerformanceCalculatorLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DesignedbyGouthamGaneshMHLabel  matlab.ui.control.Label
        CornervelocityinmsEditFieldLabel  matlab.ui.control.Label
        CornervelocityinmsEditField     matlab.ui.control.NumericEditField
        MinimumTurnRadiusinmEditFieldLabel  matlab.ui.control.Label
        MinimumTurnRadiusinmEditField   matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = turn(app,m,s,n,clmax,h)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            m=app.MassoftheAircraftinkgEditField.Value;
            s=app.Wingareainm2EditField.Value;
            n=app.MaximumloadfactorEditField.Value;
            clmax=app.CLmaxEditField.Value;
            h=app.AltitudeoftheAircraftinkmEditField.Value;
            
            if h<11
                tratio=(1-(h/44.331));
                d=1.225*(tratio)^4.256;    
            end
            if h==11
                 d=0.36392
            end
            if h>11
                d=0.036392*exp((-9.80665*([h*1000]-11000))/(287.05287*216.65));
            end
            w=m*9.8;
            corner_velocity= sqrt((2*n*w)/(s*d*clmax))
            Minimum_turn_radius= (corner_velocity^2)/(9.8*sqrt(n^2-1));
            Maximum_Turn_Rate=(9.8*sqrt(n^2-1))/(corner_velocity)
            mtr=(Maximum_Turn_Rate*180)/pi;
            app.CornervelocityinmsEditField.Value=corner_velocity;
            app.MinimumTurnRadiusinmEditField.Value=Minimum_turn_radius;
            app.MaximumTurnrateindegsEditField.Value=mtr;
              turn(app,m,s,n,clmax,h)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MassoftheAircraftinkgEditFieldLabel = uilabel(app.UIFigure);
            app.MassoftheAircraftinkgEditFieldLabel.Text = 'Mass of the Aircraft in kg ';
            app.MassoftheAircraftinkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.Wingareainm2Label = uilabel(app.UIFigure);
            app.Wingareainm2Label.Text = 'Wing area in m^2 ';
            app.Wingareainm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumloadfactorEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumloadfactorEditFieldLabel.Text = 'Maximum load factor';
            app.MaximumloadfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CLmaxEditFieldLabel = uilabel(app.UIFigure);
            app.CLmaxEditFieldLabel.Text = 'CL max ';
            app.CLmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudeoftheAircraftinkmLabel = uilabel(app.UIFigure);
            app.AltitudeoftheAircraftinkmLabel.Text = 'Altitude of the Aircraft in km ';
            app.AltitudeoftheAircraftinkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumTurnrateindegsLabel = uilabel(app.UIFigure);
            app.MaximumTurnrateindegsLabel.Text = 'Maximum Turn rate in deg/s ';
            app.MaximumTurnrateindegsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TurnPerformanceCalculatorLabel = uilabel(app.UIFigure);
            app.TurnPerformanceCalculatorLabel.Text = 'Turn Performance Calculator ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'msruas logo.jpg';
            app.DesignedbyGouthamGaneshMHLabel = uilabel(app.UIFigure);
            app.DesignedbyGouthamGaneshMHLabel.Text = 'Designed by Goutham Ganesh M H';
            app.CornervelocityinmsEditFieldLabel = uilabel(app.UIFigure);
            app.CornervelocityinmsEditFieldLabel.Text = 'Corner velocity in m/s ';
            app.CornervelocityinmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumTurnRadiusinmEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumTurnRadiusinmEditFieldLabel.Text = 'Minimum Turn Radius in m ';
            app.MinimumTurnRadiusinmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = turning_performance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end